classdef ADR < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Main                           matlab.ui.Figure
        MainGrid                       matlab.ui.container.GridLayout
        MainTabGroup                   matlab.ui.container.TabGroup
        ConfigureTab                   matlab.ui.container.Tab
        GridLayout3                    matlab.ui.container.GridLayout
        DeviceTree                     matlab.ui.container.Tree
        Panel_6                        matlab.ui.container.Panel
        GridLayout7                    matlab.ui.container.GridLayout
        SaveConfigButton               matlab.ui.control.Button
        CreateNewConfigButton          matlab.ui.control.Button
        GridLayout12                   matlab.ui.container.GridLayout
        ChannelNameEditFieldLabel      matlab.ui.control.Label
        ChannelNameField               matlab.ui.control.EditField
        RecordchannelSwitchLabel       matlab.ui.control.Label
        ChannelEnableSwitch            matlab.ui.control.Switch
        NumChannelsEnabled             matlab.ui.control.Label
        ChannelTypeTab                 matlab.ui.container.TabGroup
        VoltageTab                     matlab.ui.container.Tab
        GridLayout11                   matlab.ui.container.GridLayout
        TerminalconfigurationLabel     matlab.ui.control.Label
        TerminalConfigList             matlab.ui.control.DropDown
        BridgeTab                      matlab.ui.container.Tab
        GridLayout13                   matlab.ui.container.GridLayout
        BridgemodeDropDownLabel        matlab.ui.control.Label
        BridgemodeDropDown             matlab.ui.control.DropDown
        NominalresistanceSpinnerLabel  matlab.ui.control.Label
        NominalResistanceSpinner       matlab.ui.control.Spinner
        RangeDropDownLabel             matlab.ui.control.Label
        RangeSelect                    matlab.ui.control.DropDown
        RecordTab                      matlab.ui.container.Tab
        GridLayout                     matlab.ui.container.GridLayout
        Panel_2                        matlab.ui.container.Panel
        GridLayout5                    matlab.ui.container.GridLayout
        DataPlot                       matlab.ui.control.UIAxes
        Panel_3                        matlab.ui.container.Panel
        GridLayout4                    matlab.ui.container.GridLayout
        StartButton                    matlab.ui.control.Button
        StopButton                     matlab.ui.control.Button
        RecordingLamp                  matlab.ui.control.Lamp
        EnableLiveViewSwitch           matlab.ui.control.Switch
        EnableLiveViewLabel            matlab.ui.control.Label
        RecordDataLabel                matlab.ui.control.Label
        Panel_4                        matlab.ui.container.Panel
        GridLayout6                    matlab.ui.container.GridLayout
        LoadConfigButton               matlab.ui.control.Button
        LoadedConfig                   matlab.ui.control.EditField
        LoadedConfigLabel              matlab.ui.control.Label
        ChannelTree                    matlab.ui.container.Tree
        DisplayChannelsTree            matlab.ui.control.Label
        RecordfrequencyHzLabel         matlab.ui.control.Label
        RecordRate                     matlab.ui.control.Spinner
        DisplayfrequencyHzLabel        matlab.ui.control.Label
        DisplayRate                    matlab.ui.control.Spinner
        LivebufferlengthsLabel         matlab.ui.control.Label
        BufferLength                   matlab.ui.control.Spinner
        SaveTab                        matlab.ui.container.Tab
        GridLayout_2                   matlab.ui.container.GridLayout
        Panel_7                        matlab.ui.container.Panel
        GridLayout9                    matlab.ui.container.GridLayout
        UsermetadataTextAreaLabel      matlab.ui.control.Label
        UsermetadataTextArea           matlab.ui.control.TextArea
        SavelocationLabel              matlab.ui.control.Label
        SaveLocation                   matlab.ui.control.EditField
        SaveDataButton                 matlab.ui.control.Button
        BrowseFolderButton             matlab.ui.control.Button
        SavefilenameEditFieldLabel     matlab.ui.control.Label
        SavefilenameEditField          matlab.ui.control.EditField
        TimestampofrunEditFieldLabel   matlab.ui.control.Label
        TimestampofrunEditField        matlab.ui.control.EditField
        SaveMetaButton                 matlab.ui.control.Button
        LoadMetaButton                 matlab.ui.control.Button
        ProcessTab                     matlab.ui.container.Tab
        GridLayout10                   matlab.ui.container.GridLayout
        LoaddatafileEditFieldLabel     matlab.ui.control.Label
        LoaddatafileEditField          matlab.ui.control.EditField
        BrowseFileButton               matlab.ui.control.Button
        Panel_8                        matlab.ui.container.Panel
    end

    
    properties (Access = private)
        S % DAQ Session for recording data
        Config % Configuration data used in configuration tab
        C % Loaded configuration data
        DataAvailableListener % Callback for available data from session
        ChannelNodes % List of channels found during configuration
        DisplayChannels % Channels that are displayed in the data plot
        DisplayData % Description
        Data % Recorded data
        TimeStamps % Corresponding time stamps for recorded data
        F % Meta form data
        MetaStruct % USRMETA converted into a structure
        SaveTime % Timestamp of most recent recorded data
    end
    
    methods (Access = private)
        
        % Display data function
        function dataAvailableCallback(app,~,event)
            if strcmp(app.EnableLiveViewSwitch.Value,'On') % Livestreaming data
                BufferSize = round(app.DisplayRate.Value*app.BufferLength.Value); % Calculate buffer size
                
                for i = 1:length(app.DisplayChannels) % For each channel being displayed
                    % Shift data in Y-array
                    app.DisplayData(i).Y = dataShift(app,app.DisplayData(i).Y,event.Data(:,app.DisplayChannels(i)),BufferSize);
                    % Recalculate timestamps (it may be more efficient to use the dataShift() function instead of recalculating... Need to baseline this first though
                    app.DisplayData(i).X = linspace(0,length(app.DisplayData(i).Y)/app.DisplayRate.Value,length(app.DisplayData(i).Y));
                    % Update X,Y data in main axes
                    set(app.DataPlot.Children(i),'XData',app.DisplayData(i).X,'YData',app.DisplayData(i).Y);
                end
                
            elseif strcmp(app.EnableLiveViewSwitch.Value,'Off') % Recording data
                app.Data = [app.Data; event.Data]; % Append new data to end of app.Data
                app.TimeStamps = [app.TimeStamps; event.TimeStamps]; % Append new timestamps to app.TimeStamps
                for i = 1:length(app.DisplayChannels) % For each channel in loaded configuration
                    % Add new data in event to end of DisplayData array
                    app.DisplayData(i).Y = [app.DisplayData(i).Y; event.Data(:,app.DisplayChannels(i))];
                    app.DisplayData(i).X = [app.DisplayData(i).X; event.TimeStamps];
                    % Plot new data
                    set(app.DataPlot.Children(i),'XData',app.DisplayData(i).X,'YData',app.DisplayData(i).Y);
                end
                
            end
        end
        
        
        % Shift data function
        function results = dataShift(~,OriginalData,NewData,BufferSize)
            % This function will
            if length(OriginalData) + length(NewData) >= BufferSize % If the length of the old data and the new data combined is larger than the buffer size
                if length(NewData) >= BufferSize % If the length of the new data alone is larger than the buffer size
                    results = NewData(1:BufferSize); % Output is 1:BufferSize of new data
                else % Length of new data is less than buffer size
                    results = [NewData; OriginalData(1:BufferSize-length(NewData))]; % Shift over old data and put new data on top
                end
            else % Length of combined data is less than buffer size
                results = [NewData; OriginalData]; % Combine data
            end
        end
        
        function startLiveView(app)
            if app.S.IsRunning % Reset daq if it was previously started
                stop(app.S);
            end
            
            if isempty(app.DisplayChannels) % No channels selected to view
                uialert(app.Main,'Select at least one channel to display before enabling live view.','Error')
                app.EnableLiveViewSwitch.Value = 'Off';
                return
            end
            
            app.EnableLiveViewSwitch.Value = 'On';
            
            for i = 1:length(app.DisplayChannels) % For each channel being displayed
                % Clear X and Y data
                app.DisplayData(i).X = [];
                app.DisplayData(i).Y = [];
            end
            
            % Start observing data -- the dataAvailableListener callback will handle data as it comes through
            startBackground(app.S);
        end
        
        function stopLiveView(app)
            app.EnableLiveViewSwitch.Value = 'Off';
            if app.S.IsRunning
                stop(app.S);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % The main interface is hidden until MATLAB finishes creating
            % and loading all of the graphical components. When they're
            % ready, MATLAB shows the UI
            drawnow;
            app.MainTabGroup.Visible = true;
        end

        % Button pushed function: LoadConfigButton
        function LoadConfig(app, event)
            % First, reset the daq and get rid of the old session info
            daqreset;
            delete(app.S);
            
            % Ask user to specify which file to load
            [f,p] = uigetfile('*.mat');
            drawnow; % Update graphics
            figure(app.Main); % Return focus to app
            
            % Delete the components shown in the ChannelTree
            delete(app.ChannelTree.Children)
            
            if f == 0 % If the user cancels before selecting a file
                return % Do nothing else
            else % User specified file
                load([p f])
                if ~exist('META') == 1 % Check if Config File has a variable named META
                    uialert(app.Main,'Invalid configuration file.','Error');
                    return
                elseif ~strcmp(META.Type,'CONFIGURATION') % Check if the value of META is not CONFIGURATION
                    uialert(app.Main,'Invalid configuration file.','Error')
                    return
                end
                app.C = Configuration;
                
                % Save config data to MATLAB base workspace
                assignin('base','C',app.C);
            end
            
            % Update text field in UI
            app.LoadedConfig.Value = [p f];
            
            % Create the session. Note, if using non-NI devices, this is
            % the line to change. Currently, the app only supports a single
            % session type.
            app.S = daq.createSession('ni');
            
            % Create waitbar while app adds channels
            w = uiprogressdlg(app.Main,'Message','Loading channels...','Title','Please Wait','Value',0);
            
            for i = 1:length(app.C) % For each channel in configuration file
                if app.C(i).Enabled % Only add enabled channel
                    if strcmp(app.C(i).Type,'AnalogInput') % Make sure only AnalogInput channels are used
                        if strcmp(app.C(i).MeasurementType,'Voltage') % Load Voltage channels
                            try
                                % Add the channel
                                addAnalogInputChannel(app.S,app.C(i).DeviceID,app.C(i).Channel,app.C(i).MeasurementType);
                                % Specify the range
                                app.S.Channels(end).Range = app.C(i).SelectedRange;
                                % Attempt to specify terminal config. Some devices will have problems with this, hence the try/catch environment. Check
                                % the documentation/troubleshooting section for more info
                                app.S.Channels(end).TerminalConfig = cell2mat(app.C(i).TerminalConfig);
                                % Update UI
                                val = sprintf('%s - %s',app.C(i).Channel,app.C(i).ChannelName);
                                uitreenode(app.ChannelTree,'Text',val,'NodeData',i);
                            catch ME % Something didn't work... pass the error message to the user and let them figure it out
                                msg = sprintf('Channel addition failed: %s\n%s\n\nResolve error in configuration file, then reload configuration.',app.C(i).ChannelName,ME.message);
                                uialert(app.Main,msg,'Error')
                                delete(w);
                                return
                            end
                            
                        elseif strcmp(app.C(i).MeasurementType,'Bridge') % Strain gage is a bridge type device
                            addAnalogInputChannel(app.S,app.C(i).DeviceID,app.C(i).Channel,app.C(i).MeasurementType);
                            app.S.Channels(end).Range = app.C(i).SelectedRange;
                            app.S.Channels(end).BridgeMode = app.C(i).BridgeMode;
                            app.S.Channels(end).NominalBridgeResistance = app.C(i).NominalResistance;
                            val = sprintf('%s - %s',app.C(i).Channel,app.C(i).ChannelName);
                            uitreenode(app.ChannelTree,'Text',val,'NodeData',i);
                        end
                    end
                end
                w.Value = i/(length(app.C)); % Update waitbar value
            end
            
            % Update UI to display actual rate in session
            app.DisplayRate.Value = app.S.Rate;
            app.RecordRate.Value = app.S.Rate;
            
            % Enable all of the buttons
            app.DisplayRate.Enable = 'on';
            app.BufferLength.Enable = 'on';
            app.RecordRate.Enable = 'on';
            app.EnableLiveViewSwitch.Enable = 'on';
            
            % Specify that the session should run in continuous mode (rather than discretely)
            app.S.IsContinuous = true;
            
            % Define data available listener
            app.DataAvailableListener = addlistener(app.S,'DataAvailable', ...
                @(src,event) dataAvailableCallback(app, src, event));
            
            % Save session info in MATLAB base workspace
            assignin('base','S',app.S)
            
            % Enable rest of the buttons
            UpdateChannelTree(app);
            app.StartButton.Enable = 'on';
        end

        % Button pushed function: SaveConfigButton
        function SaveConfig(app, event)
            if isempty(app.Config) % Check to make sure a configuration has been created
                uialert(app.Main,'Create new configuration before saving.','Error')
                return
            elseif isempty(find([app.Config.Enabled]==true)) %#ok<EFIND> % Check to make sure at least one channel is enabled
                uialert(app.Main,'At least one channel must be enabled before saving.','Error');
                return
            else % We're set to go
                [f,p] = uiputfile('*.mat'); % Ask user to specify save location
                drawnow; % Update graphics
                figure(app.Main); % Return focus to app
                if f==0 % If user cancels before specifying location
                    return % Do nothing else
                else
                    META.Type = 'CONFIGURATION'; % Specify .mat type of file
                    Configuration = app.Config(find([app.Config.Enabled]==true)); %#ok<FNDSB> % Save configuration data to file
                    save([p f],'Configuration','META','-mat') % Save file
                end
            end
            
        end

        % Button pushed function: CreateNewConfigButton
        function CreateNewConfig(app, event)
            % Reset and clear old data
            daqreset;
            app.Config = [];
            
            % Create waitbar while channels are being discovered
            w = uiprogressdlg(app.Main,'Value',0.1,'Message','Creating session...');
            
            w.Message = 'Getting devices...';
            w.Value = 0.2;
            Devices = daq.getDevices();
            
            % Clear old data
            delete(app.DeviceTree.Children);
            % Initialize channel list
            app.Config(1).Channel = [];
            
            w.Message = 'Loading channels...';
            w.Value = 0.3;
            
            
            for i = 1:length(Devices) % For each discovered device
                if strcmp(Devices(i).Vendor.ID,'ni') % If device is from NI
                    if exist('TEMP','var')
                        TEMP(end+1) = Devices(i);
                    else
                        TEMP(1) = Devices(i);
                    end
                end
            end
            
            if exist('TEMP','var')
                Devices = TEMP;
            else
                uialert(app.Main,'No National Instruments devices were found.','Error')
                return
            end
            for i = 1:length(Devices)
                
                DeviceNodes(i) = uitreenode(app.DeviceTree,'Text',[Devices(i).Model ' - ' Devices(i).ID]);
                for j = 1:length(Devices(i).Subsystems)
                    SubsystemNodes(j) = uitreenode(DeviceNodes(i),'Text',Devices(i).Subsystems(j).SubsystemType);
                    for k = 1:length(Devices(i).Subsystems(j).ChannelNames)
                        app.ChannelNodes(k) = uitreenode(SubsystemNodes(j),'Text',cell2mat(Devices(i).Subsystems(j).ChannelNames(k)),'Icon','close.png','NodeData',length(app.Config));
                        
                        app.Config(end+1).Channel = cell2mat(Devices(i).Subsystems(j).ChannelNames(k));
                        app.Config(end).ChannelName = string(join([num2str(i),num2str(j),num2str(k)],","));
                        assignin('base','t',app.Config)
                        app.Config(end).Enabled = false;
                        app.Config(end).DeviceID = Devices(i).ID;
                        app.Config(end).Model = Devices(i).Model;
                        if isprop(Devices(i).Subsystems(j),'RangesAvailable')
                            app.Config(end).AvailableRanges = Devices(i).Subsystems(j).RangesAvailable;
                            app.Config(end).SelectedRange = [app.Config(end).AvailableRanges(1).Min,app.Config(end).AvailableRanges(1).Max];
                        else
                            app.Config(end).AvailableRanges = [];
                            app.Config(end).SelectedRange = [];
                        end
                        app.Config(end).Range = 1;
                        app.Config(end).MeasurementType = Devices(i).Subsystems(j).DefaultMeasurementType;
                        app.Config(end).Type = Devices(i).Subsystems(j).SubsystemType;
                        
                        if isprop(Devices(i).Subsystems(j),'TerminalConfigsAvailable')
                            app.Config(end).TerminalConfigOptions = [string(Devices(i).Subsystems(j).TerminalConfigsAvailable)];
                            app.Config(end).TerminalConfig = app.Config(end).TerminalConfigOptions(1);
                        else
                            app.Config(end).TerminalConfigOptions = [];
                            app.Config(end).TerminalConfig = 1;
                        end
                        app.Config(end).TerminalConfigNum = 1;
                        app.Config(end).Subsystem = Devices(i).Subsystems(j).SubsystemType;
                        app.Config(end).SubsystemNum = j;
                        app.Config(end).BridgeMode = 'Full';
                        app.Config(end).NominalResistance = 100;
                    end
                end
                w.Value = w.Value + i*(1-w.Value);
            end
            
            app.Config = app.Config(2:end);
            assignin('base','Config',app.Config);
            close(w)
        end

        % Selection changed function: DeviceTree
        function UpdateDeviceTree(app, event)
            selectedChannel = app.DeviceTree.SelectedNodes;
            
            if ~isempty(selectedChannel.NodeData) % Channel node selected
                num = selectedChannel.NodeData; % Channel index
                
                if strcmp(app.Config(num).MeasurementType,'Voltage') % If channel has a Voltage measurement type
                    app.ChannelTypeTab.SelectedTab = app.VoltageTab;
                    % Enable channel editing
                    app.ChannelEnableSwitch.Enable = true;
                    app.RangeSelect.Enable = true;
                    app.ChannelNameField.Editable = true;
                    app.TerminalConfigList.Enable = true;
                    app.NominalResistanceSpinner.Enable = false;
                    app.BridgemodeDropDown.Enable = false;

                    if isempty(app.Config(num).TerminalConfigOptions) % If channel doesn't have terminal configurations (analog devices)
                        app.TerminalConfigList.Items = {'none'};
                        app.TerminalConfigList.ItemsData = 0;
                        app.TerminalConfigList.Value = 0;
                        app.TerminalConfigList.Enable = false;
                        
                    else % If channel has terminal configurations (analog devices)
                        
                        if length(app.Config(num).TerminalConfigOptions) == 1
                            TConfigs = app.Config(num).TerminalConfigOptions(1);
                        else
                            for i = 1:length(app.Config(num).TerminalConfigOptions)
                                TConfigs(i) = app.Config(num).TerminalConfigOptions(i); %#ok<*AGROW>
                            end
                        end
                        
                        % Display available terminal configurations
                        app.TerminalConfigList.Items = TConfigs;
                        app.TerminalConfigList.ItemsData = 1:length(TConfigs);
                        app.TerminalConfigList.Enable = true;
                        
                        % Set drop down to value in config
                        app.TerminalConfigList.Value = app.Config(num).TerminalConfigNum;
                    end
                    
                elseif strcmp(app.Config(num).MeasurementType,'Bridge') % If channel has a bridge measurement type
                    app.ChannelTypeTab.SelectedTab = app.BridgeTab;
                    
                    % Enable channel editing
                    app.ChannelEnableSwitch.Enable = true;
                    app.ChannelNameField.Editable = true;
                    app.RangeSelect.Enable = true;
                    app.TerminalConfigList.Enable = false;
                    app.NominalResistanceSpinner.Enable = true;
                    app.BridgemodeDropDown.Enable = true;
                    
                    app.BridgemodeDropDown.Value = app.Config(num).BridgeMode;
                    app.NominalResistanceSpinner.Value = app.Config(num).NominalResistance;
                    
                end
                
                if isempty(app.Config(num).AvailableRanges) % If channel doesn't have ranges available
                    % Disable range selection drop down
                    app.RangeSelect.Items = "none";
                    app.RangeSelect.ItemsData = 0;
                    app.RangeSelect.Value = 0;
                    app.RangeSelect.Enable = false;
                    
                else % Channel has ranges avialable
                    for i = 1:length(app.Config(num).AvailableRanges) % For each available range
                        if isempty(app.Config(num).AvailableRanges(1).Units) % If range doesn't have units
                            ranges{i} = sprintf('%1.3f to %2.3f',app.Config(num).AvailableRanges(i).Min,app.Config(num).AvailableRanges(i).Max);
                        else % If range has units
                            ranges{i} = sprintf('%1.3f to %2.3f %3s',app.Config(num).AvailableRanges(i).Min,app.Config(num).AvailableRanges(i).Max,app.Config(num).AvailableRanges(i).Units);
                        end
                        
                    end
                    
                    % Display available ranges
                    app.RangeSelect.Items = ranges;
                    app.RangeSelect.ItemsData = 1:length(ranges);
                    app.RangeSelect.Enable = true;
                    
                    % Set range to selected value in config
                    app.RangeSelect.Value = app.Config(num).Range;
                end
                
                if app.Config(num).Enabled % Channel is enabled
                    app.ChannelEnableSwitch.Value = 'On'; % Set switch on
                else % Channel is not enabled
                    app.ChannelEnableSwitch.Value = 'Off'; % Set switch off
                end
                
                app.ChannelNameField.Value = app.Config(num).ChannelName; % Display channel name


            else % Channel node is not selected
                
                % Disable channel editing
                app.ChannelEnableSwitch.Value = 'Off';
                app.RangeSelect.Items = "none";
                app.RangeSelect.ItemsData = 1;
                app.RangeSelect.Value = 1;
                app.TerminalConfigList.Items = "none";
                app.TerminalConfigList.ItemsData = 1;
                app.TerminalConfigList.Value = 1;
                app.ChannelNameField.Value = 'Select channel';
                
                app.ChannelEnableSwitch.Enable = false;
                app.RangeSelect.Enable = false;
                app.ChannelNameField.Editable = false;
                app.TerminalConfigList.Enable = false;
                app.NominalResistanceSpinner.Enable = false;
                app.BridgemodeDropDown.Enable = false;
                
            end
        end

        % Value changed function: NominalResistanceSpinner
        function UpdateNomRes(app, event)
            value = app.NominalResistanceSpinner.Value;
            num = app.DeviceTree.SelectedNodes.NodeData;
            app.Config(num).NominalResistance = value;
        end

        % Value changed function: BridgemodeDropDown
        function UpdateBridgeMode(app, event)
            value = app.BridgemodeDropDown.Value;
            num = app.DeviceTree.SelectedNodes.NodeData;
            app.Config(num).BridgeMode = value;
        end

        % Value changed function: ChannelNameField
        function UpdateChannelName(app, event)
            num = app.DeviceTree.SelectedNodes.NodeData; % Channel index in app.Config
            oldName = app.Config(num).ChannelName; % Store old name in case new name is already taken
            app.Config(num).ChannelName = string(app.ChannelNameField.Value);
            if length([app.Config.ChannelName]) ~= length(unique([app.Config.ChannelName])) % Channel name already exists
                uialert(app.Main,'Channel name already exists. Pick another name.','Error');
                app.ChannelNameField.Value = oldName; % Revert to old name
                app.Config(num).ChannelName = string(oldName); % Revert to old name
                app.DeviceTree.SelectedNodes.Text = sprintf('%s - %s',app.Config(num).Channel,app.Config(num).ChannelName); % Display channel name in channel browser
                return
            end
            app.DeviceTree.SelectedNodes.Text = sprintf('%s - %s',app.Config(num).Channel,app.Config(num).ChannelName); % Display channel name in channel browser
        end

        % Value changed function: RangeSelect
        function UpdateChannelRange(app, event)
            value = app.RangeSelect.Value;
            num = app.DeviceTree.SelectedNodes.NodeData;
            app.Config(num).Range = value;
            app.Config(num).SelectedRange = [app.Config(num).AvailableRanges(value).Min,app.Config(num).AvailableRanges(value).Max];
        end

        % Value changed function: ChannelEnableSwitch
        function UpdateChannelEnabled(app, event)
            value = app.ChannelEnableSwitch.Value;
            num = app.DeviceTree.SelectedNodes.NodeData;
            
            if strcmp(value,'On')
                if ~strcmp(app.Config(num).Type,'AnalogInput')
                    uialert(app.Main,'Only analog input devices are currently supported.','Error')
                    app.ChannelEnableSwitch.Value = 'Off';
                    return
                end
                app.Config(num).Enabled = true;
                app.DeviceTree.SelectedNodes.Icon = 'tick.png';
                app.NumChannelsEnabled.Text = sprintf('Number of enabled channels: %i',sum([app.Config.Enabled]));
            else
                app.Config(num).Enabled = false;
                app.NumChannelsEnabled.Text = sprintf('Number of enabled channels: %i',sum([app.Config.Enabled]));
                app.DeviceTree.SelectedNodes.Icon = 'close.png';
            end
        end

        % Value changed function: TerminalConfigList
        function UpdateChannelTermConfig(app, event)
            value = app.TerminalConfigList.Value;
            num = app.DeviceTree.SelectedNodes.NodeData;
            app.Config(num).TerminalConfigNum = value;
            app.Config(num).TerminalConfig = app.Config(num).TerminalConfigOptions(value);
        end

        % Button pushed function: StopButton
        function StopRecording(app, event)
            stopLiveView(app);
            app.StartButton.Enable = 'on';
            app.StopButton.Enable = 'off';
            app.EnableLiveViewSwitch.Enable = 'on';
            app.RecordingLamp.Enable = 'off';
            pause(1);
            app.SaveTime = now;
            app.TimestampofrunEditField.Value = datestr(app.SaveTime,'HH:MM:SS - yyyy/mm/dd');
            app.MainTabGroup.SelectedTab = app.SaveTab;
        end

        % Button pushed function: StartButton
        function StartRecording(app, event)
            app.RecordRate.Value = app.S.Rate;
            
            if isempty(app.DisplayChannels)
                uialert(app.Main,'Select at least one channel to display before recording data.','Error')
                app.EnableLiveViewSwitch.Value = 'Off';
                return
            end
            
            for i = 1:length(app.DisplayChannels)
                app.DisplayData(i).X = [];
                app.DisplayData(i).Y = [];
            end
            
            app.StartButton.Enable = 'off';
            app.StopButton.Enable = 'on';
            app.EnableLiveViewSwitch.Value = 'Off';
            app.EnableLiveViewSwitch.Enable = 'off';
            app.UpdateLiveView(app);
            try
                app.S.Rate = app.RecordRate.Value;
            catch ME
                uialert(app.Main,ME.message,'Error')
                app.S.Rate = 10;
                app.RecordRate.Value = app.S.Rate;
                return
            end
            
            app.RecordingLamp.Enable = 'on';
            set(app.DataPlot,'XLimMode','Auto');
            
            
            startBackground(app.S);
        end

        % Value changed function: DisplayRate
        function UpdateDisplayRate(app, event)
            stopLiveView(app);
            oldRate = app.S.Rate;
            try
                app.S.Rate = app.DisplayRate.Value;
            catch ME
                uialert(app.Main,ME.message,'Error');
                app.S.Rate = oldRate;
                app.DisplayRate.Value = oldRate;
                return
            end
            
            if regexp(lastwarn,'Cannot support')
                app.DisplayRate.Value = app.S.Rate;
            end
            
            BufferSize = round(app.S.Rate*app.BufferLength.Value);
            
            if BufferSize*length(app.DisplayChannels) > 1000
                uialert(app.Main,sprintf('Live buffer size is large: %i samples. Consider reducing buffer duration or display rate.',BufferSize),'Warning','Icon','warning')
            end
        end

        % Value changed function: BufferLength
        function UpdateBufferLength(app, event)
            stopLiveView(app);
            
            BufferSize = round(app.DisplayRate.Value*app.BufferLength.Value);
            
            if BufferSize*length(app.DisplayChannels) > 1000
                uialert(app.Main,sprintf('Live buffer size is large: %i samples. Consider reducing buffer duration or display rate.',BufferSize),'Warning','Icon','warning')
            end
            
        end

        % Value changed function: EnableLiveViewSwitch
        function UpdateLiveView(app, event)
            if strcmp(app.EnableLiveViewSwitch.Value,'On')
                set(app.DataPlot,'XLim',[0 app.BufferLength.Value]);
                startLiveView(app);
            else
                stopLiveView(app);
            end
        end

        % Selection changed function: ChannelTree
        function UpdateChannelTree(app, event)
            stopLiveView(app);
            
            if ~isempty(app.ChannelTree.SelectedNodes)
                app.DisplayChannels = [app.ChannelTree.SelectedNodes.NodeData];
                cla(app.DataPlot,'reset');
                for i = 1:length(app.ChannelTree.SelectedNodes)
                    app.DisplayData(i).X = [];
                    app.DisplayData(i).Y = [];
                    plot(app.DataPlot,NaN,NaN);
                    hold(app.DataPlot,'on');
                end
            else
                app.DisplayChannels = [];
            end
            
            
        end

        % Close request function: Main
        function MainCloseRequest(app, event)
            if exist(app.S) == 1 & ~isempty(app.S) & app.S.IsRunning %#ok<AND2> 
                stop(app.S)
            end
            
            delete(app)
            
        end

        % Button pushed function: BrowseFolderButton
        function BrowseSaveFolder(app, event)
            path = uigetdir;
            drawnow; % Update graphics
            figure(app.Main); % Return focus to app
            if path == 0
                return
            end
            app.SaveLocation.Value = path;
        end

        % Button pushed function: SaveDataButton
        function SaveData(app, event)
            if isempty(app.Data)
                uialert(app.Main,'Data must be recorded before saving.','Error')
                return
            end
            if strcmp(app.SaveLocation.Value,'')
                uialert(app.Main,'Select save location before saving.','Error')
                return
            end
            
            path = app.SaveLocation.Value;
            sequence = length(dir([path '\*_S*.mat']))+1;
            filename = sprintf('%s_S%.3i.mat',datestr(app.SaveTime,'yyyymmdd_HHMMSS'),sequence);
            DATA = app.Data;
            TIMESTAMPS = app.TimeStamps;
            USRMETA = app.MetaStruct;
            META.Timestamp = app.SaveTime;
            META.SessionRate = app.S.Rate;
            META.Type = 'DATA';
            for i = 1:length(app.S.Channels)
                META.ChannelOrder(i) = string(app.C(i).ChannelName);
            end
            META.ConfigFile = app.LoadedConfig.Value;
            save([path '\' filename],'DATA','TIMESTAMPS','META','USRMETA');
            
            uialert(app.Main,['File saved as ' path '\' filename '.'],'Save Successful','Icon','info')
        end

        % Value changed function: UsermetadataTextArea
        function UpdateUSRMETA(app, event)
            value = app.UsermetadataTextArea.Value;
            if length(value) && isempty(value{1}) %#ok<ISMT>
                return
            end
            
            for i = 1:length(value)
                str = value{i};
                eqind = regexp(str,'=','all');
                if isempty(eqind)
                    uialert(app.Main,sprintf('Line %i does not contain an equal sign.',i),'Error')
                    return
                elseif length(eqind) > 1
                    uialert(app.Main,sprintf('Line %i contains more than one equal sign.',i),'Error')
                    return
                elseif regexp(str,'\s') < regexp(str,'=')
                    uialert(app.Main,sprintf('Line %i contains a space before the equal sign.',i),'Error')
                    return
                elseif isempty(regexp(str,'=+.')) %#ok<RGXP1>
                    uialert(app.Main,sprintf('No value assigned to the tag on line %i.',i),'Error')
                    return
                elseif isempty(regexp(str,'\w+=')) %#ok<RGXP1>
                    uialert(app.Main,sprintf('Line %i does not contain a tag.',i),'Error')
                    return
                elseif ~isvarname(str(1:eqind-1))
                    uialert(app.Main,sprintf('Tag on line %i is not a valid variable name.',i),'Error')
                    return
                elseif isempty(str)
                    uialert(app.Main,sprintf('Line %i is empty.',i),'Error')
                    return
                end
                line = split(str,"=");
                tag(i) = line(1);
                if length(tag) ~= length(unique(tag))
                    uialert(app.Main,'There exists non-unique tags.','Error')
                    return
                end
                
                val(i) = line(2);
            end
            app.MetaStruct = cell2struct(val',tag,1);
            
            structDisp = "META structure stored as:\n";
            for i = 1:length(tag)
                structDisp(i+1) = string(sprintf('    META.%s = %s',tag{i},val{i}));
            end
            structDisp = join(structDisp,'\n');
            structDisp = sprintf(structDisp);
            uialert(app.Main,structDisp,'Success','icon','success')
            
        end

        % Button pushed function: SaveMetaButton
        function SaveUSRMETA(app, event)
            if isempty(app.MetaStruct)
                uialert(app.Main,'Meta data is empty.','Error')
            else
                [f,p] = uiputfile('*.mat');
                drawnow; % Update graphics
                figure(app.Main); % Return focus to app
                if f==0
                    return
                else
                    USRMETA = app.MetaStruct;
                    META.Type = 'META';
                    save([p f],'META','USRMETA','-mat')
                end
            end
        end

        % Button pushed function: LoadMetaButton
        function LoadUSRMETA(app, event)
            [f,p] = uigetfile('*.mat');
            drawnow; % Update graphics
            figure(app.Main); % Return focus to app
            if f == 0
                return
            else
                load([p f]);
                if ~exist('META') == 1
                    uialert(app.Main,'Invalid USRMETA file.','Error');
                    return
                elseif ~strcmp(META.Type,'META')
                    uialert(app.Main,'Invalid USRMETA file.','Error')
                    return
                end
            end
            tag = fieldnames(USRMETA);
            for i = 1:length(fieldnames(USRMETA))
                val{i} = USRMETA.(tag{i});
                metastring{i} = sprintf('%s=%s\n',tag{i},val{i});
            end
            app.UsermetadataTextArea.Value = metastring;
            app.MetaStruct = USRMETA;
        end

        % Button pushed function: BrowseFileButton
        function ProcessData(app, event)
            % Prompt user to select file
            [f,p] = uigetfile('*.mat');
            drawnow; % Update graphics
            figure(app.Main); % Return focus to app
            
            % Load the file
            if f == 0 % If the user cancelled before selecting the file
                return
            else % File was selected
                load([p f])
                if ~exist('META') == 1 % If the file does not have a variable named 'META'
                    uialert(app.Main,'Invalid save file.','Error');
                    return
                elseif ~strcmp(META.Type,'DATA') % If .mat file is not of type 'DATA'
                    uialert(app.Main,'Invalid save file.','Error')
                    return
                end
                
                % Save variables to base workspace
                assignin('base','DATA',DATA)
                assignin('base','USRMETA',USRMETA)
                assignin('base','META',META)
                assignin('base','TIMESTAMPS',TIMESTAMPS)
            end
            
            % ADD CODE BELOW HERE
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Main = uifigure('Visible', 'off');
            app.Main.Name = 'Analog Data Recorder';
            app.Main.CloseRequestFcn = createCallbackFcn(app, @MainCloseRequest, true);
            app.MainGrid = uigridlayout(app.Main);
            app.MainTabGroup = uitabgroup(app.MainGrid);
            app.MainTabGroup.Visible = 'off';
            app.ConfigureTab = uitab(app.MainTabGroup);
            app.ConfigureTab.Title = 'Configure';
            app.GridLayout3 = uigridlayout(app.ConfigureTab);
            app.DeviceTree = uitree(app.GridLayout3);
            app.DeviceTree.SelectionChangedFcn = createCallbackFcn(app, @UpdateDeviceTree, true);
            app.Panel_6 = uipanel(app.GridLayout3);
            app.GridLayout7 = uigridlayout(app.Panel_6);
            app.SaveConfigButton = uibutton(app.GridLayout7, 'push');
            app.SaveConfigButton.ButtonPushedFcn = createCallbackFcn(app, @SaveConfig, true);
            app.SaveConfigButton.Text = 'Save Configuration';
            app.CreateNewConfigButton = uibutton(app.GridLayout7, 'push');
            app.CreateNewConfigButton.ButtonPushedFcn = createCallbackFcn(app, @CreateNewConfig, true);
            app.CreateNewConfigButton.Text = 'Create New Configuration';
            app.GridLayout12 = uigridlayout(app.GridLayout3);
            app.ChannelNameEditFieldLabel = uilabel(app.GridLayout12);
            app.ChannelNameEditFieldLabel.Text = 'Channel Name:';
            app.ChannelNameField = uieditfield(app.GridLayout12, 'text');
            app.ChannelNameField.ValueChangedFcn = createCallbackFcn(app, @UpdateChannelName, true);
            app.ChannelNameField.Editable = 'off';
            app.ChannelNameField.Value = 'Select channel';
            app.RecordchannelSwitchLabel = uilabel(app.GridLayout12);
            app.RecordchannelSwitchLabel.Text = 'Record channel:';
            app.ChannelEnableSwitch = uiswitch(app.GridLayout12, 'slider');
            app.ChannelEnableSwitch.ValueChangedFcn = createCallbackFcn(app, @UpdateChannelEnabled, true);
            app.ChannelEnableSwitch.Enable = 'off';
            app.NumChannelsEnabled = uilabel(app.GridLayout12);
            app.NumChannelsEnabled.Text = 'Number of enabled channels: 0';
            app.ChannelTypeTab = uitabgroup(app.GridLayout12);
            app.VoltageTab = uitab(app.ChannelTypeTab);
            app.VoltageTab.Title = 'Voltage';
            app.GridLayout11 = uigridlayout(app.VoltageTab);
            app.TerminalconfigurationLabel = uilabel(app.GridLayout11);
            app.TerminalconfigurationLabel.Text = 'Terminal configuration:';
            app.TerminalConfigList = uidropdown(app.GridLayout11);
            app.TerminalConfigList.Items = {'none'};
            app.TerminalConfigList.ValueChangedFcn = createCallbackFcn(app, @UpdateChannelTermConfig, true);
            app.TerminalConfigList.Value = 'none';
            app.BridgeTab = uitab(app.ChannelTypeTab);
            app.BridgeTab.Title = 'Bridge';
            app.GridLayout13 = uigridlayout(app.BridgeTab);
            app.BridgemodeDropDownLabel = uilabel(app.GridLayout13);
            app.BridgemodeDropDownLabel.Text = 'Bridge mode:';
            app.BridgemodeDropDown = uidropdown(app.GridLayout13);
            app.BridgemodeDropDown.Items = {'Full', 'Half', 'Quarter'};
            app.BridgemodeDropDown.Editable = 'on';
            app.BridgemodeDropDown.ValueChangedFcn = createCallbackFcn(app, @UpdateBridgeMode, true);
            app.BridgemodeDropDown.Enable = 'off';
            app.BridgemodeDropDown.Value = 'Full';
            app.NominalresistanceSpinnerLabel = uilabel(app.GridLayout13);
            app.NominalresistanceSpinnerLabel.Text = 'Nominal resistance:';
            app.NominalResistanceSpinner = uispinner(app.GridLayout13);
            app.NominalResistanceSpinner.Limits = [0 Inf];
            app.NominalResistanceSpinner.ValueChangedFcn = createCallbackFcn(app, @UpdateNomRes, true);
            app.NominalResistanceSpinner.Enable = 'off';
            app.RangeDropDownLabel = uilabel(app.GridLayout12);
            app.RangeDropDownLabel.Text = 'Range:';
            app.RangeSelect = uidropdown(app.GridLayout12);
            app.RangeSelect.Items = {'none'};
            app.RangeSelect.ValueChangedFcn = createCallbackFcn(app, @UpdateChannelRange, true);
            app.RangeSelect.Value = 'none';
            app.RecordTab = uitab(app.MainTabGroup);
            app.RecordTab.Title = 'Record';
            app.GridLayout = uigridlayout(app.RecordTab);
            app.Panel_2 = uipanel(app.GridLayout);
            app.GridLayout5 = uigridlayout(app.Panel_2);
            app.DataPlot = uiaxes(app.GridLayout5);
            title(app.DataPlot, 'Live Data')
            xlabel(app.DataPlot, 'Time (s)')
            ylabel(app.DataPlot, '')
            app.Panel_3 = uipanel(app.GridLayout);
            app.GridLayout4 = uigridlayout(app.Panel_3);
            app.StartButton = uibutton(app.GridLayout4, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartRecording, true);
            app.StartButton.Enable = 'off';
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.GridLayout4, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopRecording, true);
            app.StopButton.Enable = 'off';
            app.StopButton.Text = 'Stop';
            app.RecordingLamp = uilamp(app.GridLayout4);
            app.RecordingLamp.Enable = 'off';
            app.EnableLiveViewSwitch = uiswitch(app.GridLayout4, 'slider');
            app.EnableLiveViewSwitch.ValueChangedFcn = createCallbackFcn(app, @UpdateLiveView, true);
            app.EnableLiveViewSwitch.Enable = 'off';
            app.EnableLiveViewLabel = uilabel(app.GridLayout4);
            app.EnableLiveViewLabel.Text = 'Enable Live View:';
            app.RecordDataLabel = uilabel(app.GridLayout4);
            app.RecordDataLabel.Text = 'Record data:';
            app.Panel_4 = uipanel(app.GridLayout);
            app.GridLayout6 = uigridlayout(app.Panel_4);
            app.LoadConfigButton = uibutton(app.GridLayout6, 'push');
            app.LoadConfigButton.ButtonPushedFcn = createCallbackFcn(app, @LoadConfig, true);
            app.LoadConfigButton.Text = 'Load Configuration File';
            app.LoadedConfig = uieditfield(app.GridLayout6, 'text');
            app.LoadedConfig.Editable = 'off';
            app.LoadedConfig.Value = 'none';
            app.LoadedConfigLabel = uilabel(app.GridLayout6);
            app.LoadedConfigLabel.Text = 'Loaded file:';
            app.ChannelTree = uitree(app.GridLayout6);
            app.ChannelTree.Multiselect = 'on';
            app.ChannelTree.SelectionChangedFcn = createCallbackFcn(app, @UpdateChannelTree, true);
            app.DisplayChannelsTree = uilabel(app.GridLayout6);
            app.DisplayChannelsTree.Text = 'Display channels:';
            app.RecordfrequencyHzLabel = uilabel(app.GridLayout6);
            app.RecordfrequencyHzLabel.Text = 'Record frequency (Hz):';
            app.RecordRate = uispinner(app.GridLayout6);
            app.RecordRate.Limits = [0 Inf];
            app.RecordRate.Enable = 'off';
            app.RecordRate.Value = 10;
            app.DisplayfrequencyHzLabel = uilabel(app.GridLayout6);
            app.DisplayfrequencyHzLabel.Text = 'Display frequency (Hz):';
            app.DisplayRate = uispinner(app.GridLayout6);
            app.DisplayRate.Limits = [0 Inf];
            app.DisplayRate.ValueChangedFcn = createCallbackFcn(app, @UpdateDisplayRate, true);
            app.DisplayRate.Enable = 'off';
            app.DisplayRate.Value = 10;
            app.LivebufferlengthsLabel = uilabel(app.GridLayout6);
            app.LivebufferlengthsLabel.Text = 'Live buffer length (s):';
            app.BufferLength = uispinner(app.GridLayout6);
            app.BufferLength.Limits = [1 Inf];
            app.BufferLength.ValueChangedFcn = createCallbackFcn(app, @UpdateBufferLength, true);
            app.BufferLength.Enable = 'off';
            app.BufferLength.Value = 5;
            app.SaveTab = uitab(app.MainTabGroup);
            app.SaveTab.Title = 'Save';
            app.GridLayout_2 = uigridlayout(app.SaveTab);
            app.Panel_7 = uipanel(app.GridLayout_2);
            app.GridLayout9 = uigridlayout(app.Panel_7);
            app.UsermetadataTextAreaLabel = uilabel(app.GridLayout9);
            app.UsermetadataTextAreaLabel.Text = 'User meta data:';
            app.UsermetadataTextArea = uitextarea(app.GridLayout9);
            app.UsermetadataTextArea.ValueChangedFcn = createCallbackFcn(app, @UpdateUSRMETA, true);
            app.UsermetadataTextArea.Tooltip = {'See Technical User''s Manual for formatting.'};
            app.SavelocationLabel = uilabel(app.GridLayout9);
            app.SavelocationLabel.Text = 'Save location:';
            app.SaveLocation = uieditfield(app.GridLayout9, 'text');
            app.SaveDataButton = uibutton(app.GridLayout9, 'push');
            app.SaveDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveData, true);
            app.SaveDataButton.Text = 'Save Data';
            app.BrowseFolderButton = uibutton(app.GridLayout9, 'push');
            app.BrowseFolderButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseSaveFolder, true);
            app.BrowseFolderButton.Text = 'Browse Folder';
            app.SavefilenameEditFieldLabel = uilabel(app.GridLayout9);
            app.SavefilenameEditFieldLabel.Text = 'Save file name:';
            app.SavefilenameEditField = uieditfield(app.GridLayout9, 'text');
            app.SavefilenameEditField.Editable = 'off';
            app.TimestampofrunEditFieldLabel = uilabel(app.GridLayout9);
            app.TimestampofrunEditFieldLabel.Text = 'Timestamp of run:';
            app.TimestampofrunEditField = uieditfield(app.GridLayout9, 'text');
            app.TimestampofrunEditField.Editable = 'off';
            app.SaveMetaButton = uibutton(app.GridLayout9, 'push');
            app.SaveMetaButton.ButtonPushedFcn = createCallbackFcn(app, @SaveUSRMETA, true);
            app.SaveMetaButton.Text = 'Save Meta';
            app.LoadMetaButton = uibutton(app.GridLayout9, 'push');
            app.LoadMetaButton.ButtonPushedFcn = createCallbackFcn(app, @LoadUSRMETA, true);
            app.LoadMetaButton.Text = 'Load Meta';
            app.ProcessTab = uitab(app.MainTabGroup);
            app.ProcessTab.Title = 'Process';
            app.GridLayout10 = uigridlayout(app.ProcessTab);
            app.LoaddatafileEditFieldLabel = uilabel(app.GridLayout10);
            app.LoaddatafileEditFieldLabel.Text = 'Load data file:';
            app.LoaddatafileEditField = uieditfield(app.GridLayout10, 'text');
            app.BrowseFileButton = uibutton(app.GridLayout10, 'push');
            app.BrowseFileButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessData, true);
            app.BrowseFileButton.Text = 'Browse File';
            app.Panel_8 = uipanel(app.GridLayout10);
            % Show the figure after all components are created
            app.Main.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ADR

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Main)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Main)
        end
    end
end